classdef Classifier_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        start_classification  matlab.ui.control.Button
        initialization        matlab.ui.control.Button
        clear_graph           matlab.ui.control.Button
        chage_class           matlab.ui.control.StateButton
        start_pointing        matlab.ui.control.StateButton
        DropDownLabel         matlab.ui.control.Label
        DropDown              matlab.ui.control.DropDown
        TextArea              matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        Property % Description
        P1
        P2
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: initialization
        function initializationButtonPushed(app, event)
            app.P1=[3,0;3,1;0,1];
            app.P2=[6,0;4,4;3,7];
            figure(1);
            scatter(app.P1(:,1),app.P1(:,2),'gs')
            hold on
            scatter(app.P2(:,1),app.P2(:,2),'r*')
            hold on
            grid on
        end

        % Button pushed function: clear_graph
        function clear_graphButtonPushed(app, event)
            app.P1=[];
            app.P2=[];
            delete(figure(1))
            figure(1);
            axis([-10,10,0,5]);
            sp = app.start_pointing.Value;
            figure(1);
            spoint(sp)
            function spoint(sp)
                if sp==1
                    set(gcf,'WindowButtonDownFcn',@ButttonDownFcn)
                    set(gcf,'Pointer','hand')
                elseif sp==0
                    set(gcf,'WindowButtonDownFcn',@nothing)
                end
            end
            function ButttonDownFcn(src,event)
            pt = get(gca,'CurrentPoint');
            x = pt(1,1);
            y = pt(1,2);
            if app.chage_class.Value==0
                pc='r*';
                
            elseif app.chage_class.Value==1
                pc='gs';
                
            end
            scatter(x,y,pc)
            hold on
            end
            function nothing(src,event)
            end
        end

        % Value changed function: start_pointing
        function start_pointingValueChanged(app, event)
            sp = app.start_pointing.Value;
            figure(1);
            axis([-10,10,0,5]);
            spoint(sp)
            function spoint(sp)
                if sp==1
                    axis([-10,10,0,5]);
                    set(gcf,'WindowButtonDownFcn',@ButttonDownFcn)
                    set(gcf,'Pointer','hand')
                elseif sp==0
                    set(gcf,'WindowButtonDownFcn',@nothing)
                end
            end
            function ButttonDownFcn(src,event)
            axis([-10,10,0,5]);
            pt = get(gca,'CurrentPoint');
            x = pt(1,1);
            y = pt(1,2);
            fprintf('x=%d,y=%d',x,y);
            if app.chage_class.Value==0
                pc='r*';
                app.P1=[app.P1',[x,y]']';
            elseif app.chage_class.Value==1
                pc='gs';
                app.P2=[app.P2',[x,y]']';
            end
            axis([-10,10,0,5]);
            scatter(x,y,pc)
            hold on
            end
            function nothing(src,event)
                set(app.Lamp)

            end
            
        end

        % Button pushed function: start_classification
        function start_classificationButtonPushed(app, event)
            if app.DropDown.Value=='感知机算法'
                X=[app.P1',app.P2']';
                y=[ones(size(app.P1,1),1)',-1*ones(size(app.P2,1),1)']';  
                L=size(X,1);
                %扩充矩阵
                Xe=[X,ones(L,1)];
                for j=1:L
                    Xe(j,:)=Xe(j,:)*y(j);
                end
                %随机取初始化权向量
                W=randperm(20,3);
                %开始迭代
                W=Perceptron(Xe,W);
                %画图
                x=-10:10;
                line=(-W(3)-W(1)*x)/W(2);
                plot(x,line)
                hold on
                grid on

            elseif app.DropDown.Value=='H-K算法'
                X=[app.P1',app.P2']';
                y=[ones(size(app.P1,1),1)',-1*ones(size(app.P2,1),1)']';  
                %扩充矩阵
                L=size(X,1);
                Xe=[X,ones(L,1)];
                for j=1:L
                    Xe(j,:)=Xe(j,:)*y(j);
                end
                %随机取初始化权向量
                B=randperm(L+3,L);
                %开始迭代
                W=H_K_Perceptron(Xe,B);
                x=-10:10;
                line=(-W(3)-W(1)*x)/W(2);
                plot(x,line)
                hold on
                grid on
             elseif app.DropDown.Value=='F-R算法'
                 X=[app.P1',app.P2']';
                 m1=mean(app.P1,1);
                 m2=mean(app.P2,1);
                 L1=size(app.P1,1);
                 L2=size(app.P2,1);
                 Sw=0
                 for j=1:L1
                    Sw=Sw+(app.P1(j,:)-m1)*(app.P1(j,:)-m1)'
                 end
                 for j=1:L2
                    Sw=Sw+(app.P2(j,:)-m1)*(app.P2(j,:)-m1)'
                 end
                 W=inv(Sw)*(m1-m2)';
                 B=sum(X*W)/(L1+L2);
                 x=-10:10;
                 line=(B-W(2)*x)/W(1);
                 plot(x,line)
                 hold on
                 grid on  
            end
            
        function W = Perceptron(Xe,W)
            
            N=size(Xe,1);
            count=0;
            step=0;
            while count<N
            for i=1:N
                G=W*Xe(i,:)';
                if G>0
                count=count+1;
                else
                count=0;
                W=W+Xe(i,:);
                end
                if count==N
                    break;
                end
                step=step+1;
                if step>20000
                    break;
                end
            end
            end
        end
        
        function W = H_K_Perceptron(Xe,B)
            
            N=size(Xe,1);
            step=0;
            XX=inv(Xe'*Xe)*Xe';
            W=XX*B';
            e=Xe*W-B';
            while min(e)<0.000001
              B=(B'+e+abs(e))';
              W=XX*B';
              e=Xe*W-B';
              step=step+1;
              if step>20000
                  break;
              end
            end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.start_classification = uibutton(app.UIFigure, 'push');
            app.start_classification.ButtonPushedFcn = createCallbackFcn(app, @start_classificationButtonPushed, true);
            app.start_classification.Text = '开始判别';
            app.initialization = uibutton(app.UIFigure, 'push');
            app.initialization.ButtonPushedFcn = createCallbackFcn(app, @initializationButtonPushed, true);
            app.initialization.Text = '导入预设置测试数据';
            app.clear_graph = uibutton(app.UIFigure, 'push');
            app.clear_graph.ButtonPushedFcn = createCallbackFcn(app, @clear_graphButtonPushed, true);
            app.clear_graph.Text = '清空数据';
            app.chage_class = uibutton(app.UIFigure, 'state');
            app.chage_class.Text = '切换输入点类别';
            app.start_pointing = uibutton(app.UIFigure, 'state');
            app.start_pointing.ValueChangedFcn = createCallbackFcn(app, @start_pointingValueChanged, true);
            app.start_pointing.Text = '鼠标点击输入点';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = '选择算法';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'感知机算法', 'H-K算法', 'F-R算法'};
            app.DropDown.Value = '感知机算法';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'1、选择需要使用的算法'; ''; '2、可以导入预设值的数据或者自行使用鼠标绘制不同类别的点集进行判别'; ''; '3、执行开始判别'; ''; '4、清空数据重新使用其他判别'; ''; '注：在鼠标输入点后，若清空数据。要重新点击激活****鼠标点击输入点****选项。否则会报错！！！'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Classifier_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end